classdef scanner_sim_ge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SequenceTab                     matlab.ui.container.Tab
        SliceGapmmEditField             matlab.ui.control.NumericEditField
        SliceGapmmEditFieldLabel        matlab.ui.control.Label
        RDSDataDirectoryEditField       matlab.ui.control.EditField
        RDSDataDirectoryEditFieldLabel  matlab.ui.control.Label
        ADCSamplesB0EditField           matlab.ui.control.NumericEditField
        ADCSamplesB0EditFieldLabel      matlab.ui.control.Label
        TRB0msEditField                 matlab.ui.control.NumericEditField
        TRB0msEditFieldLabel            matlab.ui.control.Label
        TE2B0msEditField                matlab.ui.control.NumericEditField
        TE2B0msEditFieldLabel           matlab.ui.control.Label
        TE1B0msEditField                matlab.ui.control.NumericEditField
        TE1B0msEditFieldLabel           matlab.ui.control.Label
        FlipAngleB0EditField            matlab.ui.control.NumericEditField
        FlipAngleB0EditFieldLabel       matlab.ui.control.Label
        NintB0EditField                 matlab.ui.control.NumericEditField
        NintB0EditFieldLabel            matlab.ui.control.Label
        NB0EditField                    matlab.ui.control.NumericEditField
        NB0Label                        matlab.ui.control.Label
        B0CheckBox                      matlab.ui.control.CheckBox
        ADCSamplesEditField             matlab.ui.control.NumericEditField
        ADCSamplesEditFieldLabel        matlab.ui.control.Label
        RunRDSButton                    matlab.ui.control.Button
        RDSDirectoryEditField           matlab.ui.control.EditField
        RDSDirectoryEditFieldLabel      matlab.ui.control.Label
        NslicesEditField                matlab.ui.control.NumericEditField
        NslicesEditFieldLabel           matlab.ui.control.Label
        ConfigListBox                   matlab.ui.control.ListBox
        ConfigListBoxLabel              matlab.ui.control.Label
        ConfigDirectoryEditField        matlab.ui.control.EditField
        ConfigDirectoryEditFieldLabel   matlab.ui.control.Label
        RunDirectoryEditField           matlab.ui.control.EditField
        RunDirectoryEditFieldLabel      matlab.ui.control.Label
        NintEditField                   matlab.ui.control.NumericEditField
        NintEditFieldLabel              matlab.ui.control.Label
        BandwidthkHzEditField           matlab.ui.control.NumericEditField
        BandwidthkHzEditFieldLabel      matlab.ui.control.Label
        OversamplingEditField           matlab.ui.control.NumericEditField
        OversamplingEditFieldLabel      matlab.ui.control.Label
        pgeXentryEditField              matlab.ui.control.NumericEditField
        pgeXentryEditFieldLabel         matlab.ui.control.Label
        SeqnameEditField                matlab.ui.control.EditField
        SeqnameEditFieldLabel           matlab.ui.control.Label
        ScannerConfigurationsTextArea   matlab.ui.control.TextArea
        ScannerConfigurationsTextAreaLabel  matlab.ui.control.Label
        RFSpoilingIncrementEditField    matlab.ui.control.NumericEditField
        RFSpoilingIncrementEditFieldLabel  matlab.ui.control.Label
        ADCDwellmsEditField             matlab.ui.control.NumericEditField
        ADCDwellmsEditFieldLabel        matlab.ui.control.Label
        NyEditField                     matlab.ui.control.NumericEditField
        NyEditFieldLabel                matlab.ui.control.Label
        NxEditField                     matlab.ui.control.NumericEditField
        NxEditFieldLabel                matlab.ui.control.Label
        MainPSDDropDown                 matlab.ui.control.DropDown
        mainPSDDropDownLabel            matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        DummyEditField                  matlab.ui.control.NumericEditField
        DummyEditFieldLabel             matlab.ui.control.Label
        ReceiveGainCalibrationEditField  matlab.ui.control.NumericEditField
        ReceiveGainCalibrationEditFieldLabel  matlab.ui.control.Label
        FlipAngleEditField              matlab.ui.control.NumericEditField
        FlipAngleEditFieldLabel         matlab.ui.control.Label
        TEmsEditField                   matlab.ui.control.NumericEditField
        TEmsEditFieldLabel              matlab.ui.control.Label
        TRmsEditField                   matlab.ui.control.NumericEditField
        TRmsEditFieldLabel              matlab.ui.control.Label
        SliceThicknessmmEditField       matlab.ui.control.NumericEditField
        SliceThicknessmmEditFieldLabel  matlab.ui.control.Label
        PhaseFOVcmEditField             matlab.ui.control.NumericEditField
        PhaseFOVcmEditFieldLabel        matlab.ui.control.Label
        FreqFOVcmEditField              matlab.ui.control.NumericEditField
        FreqFOVcmEditFieldLabel         matlab.ui.control.Label
        fatsatCheckBox                  matlab.ui.control.CheckBox
        MergeConfigButton               matlab.ui.control.Button
        MoveDownConfigButton            matlab.ui.control.Button
        MoveUpConfigButton              matlab.ui.control.Button
        RemoveConfigFileButton          matlab.ui.control.Button
        AddConfigFilesButton            matlab.ui.control.Button
        RFSpoilingCheckBox              matlab.ui.control.CheckBox
        MinTRCheckBox                   matlab.ui.control.CheckBox
        MinTECheckBox                   matlab.ui.control.CheckBox
        GeneratePSDButton               matlab.ui.control.Button
        SetDirectoryButton              matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        PlotSequenceDiagramButton       matlab.ui.control.Button
        PlotkspaceTrajectoryButton      matlab.ui.control.Button
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        GREReconstructionTab            matlab.ui.container.Tab
        RefreshRDSButton                matlab.ui.control.Button
        RDSdataDropDown                 matlab.ui.control.DropDown
        RDSdataDropDownLabel            matlab.ui.control.Label
        ReconComparisonTextArea         matlab.ui.control.TextArea
        ReconComparisonTextAreaLabel    matlab.ui.control.Label
        ReconNyEditField                matlab.ui.control.NumericEditField
        ReconNyEditFieldLabel           matlab.ui.control.Label
        ReconNxEditField                matlab.ui.control.NumericEditField
        ReconNxEditFieldLabel           matlab.ui.control.Label
        PrescanEditField                matlab.ui.control.NumericEditField
        PrescanEditFieldLabel           matlab.ui.control.Label
        ReconDirectoryEditField         matlab.ui.control.EditField
        ReconDirectoryEditFieldLabel    matlab.ui.control.Label
        ComparewithProductButton        matlab.ui.control.Button
        SetReconDirectoryButton         matlab.ui.control.Button
        RunGREReconButton               matlab.ui.control.Button
        DiffAxes                        matlab.ui.control.UIAxes
        ProductAxes                     matlab.ui.control.UIAxes
        PhaseAxes                       matlab.ui.control.UIAxes
        MagnitudeAxes                   matlab.ui.control.UIAxes
        KSpaceAxes                      matlab.ui.control.UIAxes
        SpiralReconstructionTab         matlab.ui.container.Tab
        DualEchoDeltaTEmsEditField      matlab.ui.control.NumericEditField
        DualEchoDeltaTEmsEditFieldLabel  matlab.ui.control.Label
        RunSpiralReconwithDualEchoButton  matlab.ui.control.Button
        BrowseDualEchoButton            matlab.ui.control.Button
        DualEchoGREEditField            matlab.ui.control.EditField
        DualEchoGREEditFieldLabel       matlab.ui.control.Label
        SamplesperB0InterleafEditField  matlab.ui.control.NumericEditField
        SamplesperB0InterleafEditFieldLabel  matlab.ui.control.Label
        SamplesperInterleafEditField    matlab.ui.control.NumericEditField
        SamplesperInterleafEditFieldLabel  matlab.ui.control.Label
        RunSpiralReconRDSButton         matlab.ui.control.Button
        BrowseSpiralRDSButton           matlab.ui.control.Button
        SpiralRDSDataEditField          matlab.ui.control.EditField
        SpiralRDSDataEditFieldLabel     matlab.ui.control.Label
        FoVcmEditField                  matlab.ui.control.NumericEditField
        FoVcmEditFieldLabel             matlab.ui.control.Label
        BrowseB0kspButton               matlab.ui.control.Button
        BrowsekspButton                 matlab.ui.control.Button
        BrowseScanArchiveButton         matlab.ui.control.Button
        B0kspFileEditField              matlab.ui.control.EditField
        B0kspFileEditFieldLabel         matlab.ui.control.Label
        kspFileEditField                matlab.ui.control.EditField
        kspFileEditFieldLabel           matlab.ui.control.Label
        ScanArchiveFileEditField        matlab.ui.control.EditField
        ScanArchiveFileEditFieldLabel   matlab.ui.control.Label
        SpiralReconPathEditField        matlab.ui.control.EditField
        SpiralReconPathEditFieldLabel   matlab.ui.control.Label
        B0Label                         matlab.ui.control.Label
        TE2msEditField                  matlab.ui.control.NumericEditField
        TE2msEditFieldLabel             matlab.ui.control.Label
        TE1msEditField                  matlab.ui.control.NumericEditField
        TE1msEditFieldLabel             matlab.ui.control.Label
        B0NxEditField                   matlab.ui.control.NumericEditField
        B0NxEditFieldLabel              matlab.ui.control.Label
        B0ShotsEditField                matlab.ui.control.NumericEditField
        B0ShotsEditFieldLabel           matlab.ui.control.Label
        HighresolutionLabel             matlab.ui.control.Label
        DwellmsEditField                matlab.ui.control.NumericEditField
        DwellmsEditFieldLabel           matlab.ui.control.Label
        CalibrationEditField            matlab.ui.control.NumericEditField
        CalibrationEditFieldLabel       matlab.ui.control.Label
        Predictedkmaxcm1EditField       matlab.ui.control.NumericEditField
        Predictedkmaxcm1EditFieldLabel  matlab.ui.control.Label
        HighresNxEditField              matlab.ui.control.NumericEditField
        HighresNxEditFieldLabel         matlab.ui.control.Label
        NleafEditField                  matlab.ui.control.NumericEditField
        NleafEditFieldLabel             matlab.ui.control.Label
        RunSpiralReconScanArchiveButton  matlab.ui.control.Button
        zmaphr                          matlab.ui.control.UIAxes
        zmap                            matlab.ui.control.UIAxes
        B0Phase2                        matlab.ui.control.UIAxes
        B0Phase1                        matlab.ui.control.UIAxes
        B0Img2                          matlab.ui.control.UIAxes
        B0Img1                          matlab.ui.control.UIAxes
        Difference                      matlab.ui.control.UIAxes
        Reconwithb0                     matlab.ui.control.UIAxes
        Reconwithoutb0                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % properties are global states that can be accessed with app.X
        RunDir % where the app is installed and output is
        ResourceDir % where the folders are packaged
        LogFile % add this to log errors
        defaultParams % default GUI parameters
        seq % the sequence generated
        S % waveform to be plotted
        SelectedConfigFiles = {} % config files selected from the app
        MergedCV = struct() % HW specifications
        ConfigDir = ''
        ReconDir = ''
        ReconState
        AppPathFile % full path to app_path.txt
        PgeDir % location of the interpreter, .pge, .entry files
    end
    
    methods (Access = private)
        % A function to log error messages to a log file
        function logMessage(app, msg, level)
            % Default level
            if nargin < 3
                level = 'INFO';
            end

            % Ensure log file exists
            if isempty(app.LogFile)
                warning('Log file not initiated.');
                app.StatusTextArea.Value = {'Log file not initiated.'};
                return;
            end
            
            fid = fopen(app.LogFile, 'a');
            if fid == -1
                warning('Could not open log file: %s', app.LogFile);
                return;
            end

            timestamp = datestr(now);
            fullMsg = sprintf('[%s] %s: %s', timestamp, level, msg);

            % Write full message to log file
            fprintf(fid, '%s\n', fullMsg);
            fclose(fid);
            
            maxLines = 100;
            current = app.StatusTextArea.Value;
            % Update the status text box
            switch level
                case 'ERROR'            
                    updated = [current; {['ERROR: ' msg]}];
                    if numel(updated) > maxLines
                        updated = updated(end-maxLines+1:end);
                    end
                    app.StatusTextArea.Value = updated;
                case 'INFO'
                    updated = [current; {msg}];
                    if numel(updated) > maxLines
                        updated = updated(end-maxLines+1:end);
                    end
                    app.StatusTextArea.Value = updated;
                case 'DEBUG'
                    % Does nothing in status box
            end
            % Force UI to update immediately
            drawnow;
        end

        function defaultParams = getDefaultParams(app)
            % A function to package default sequence parameters
            defaultParams = struct( ...
                'fov_x', 25.6, ...  % cm
                'fov_y', 25.6, ...  % cm
                'slice_thickness', 3, ...  % mm
                'sliceGap', 1.5, ... % mm
                'dwell', 0.02, ...  % ms
                'TE', 15, ...  % ms
                'TR', 25, ...  % ms
                'rfSpoilingInc', 117, ...
                'Nx', 256, ...
                'Ny', 256, ...
                'alpha', 10, ...
                'pislquant', 10, ...
                'dda', 20, ...
                'minTE', true, ...
                'minTR', false, ...
                'rfSpoil', false, ...
                'oversampling', 2, ...
                'BW', 125, ... % kHz
                'Nint', 16, ...
                'NSlices', 1, ...
                'fatSat', 0, ...
                'ADCSamples', 16384, ...
                'doB0Map', 0, ...
                'NB0', 32, ...
                'NintB0', 1, ...
                'alphaB0', 5, ...
                'TE1B0', 3, ... % ms
                'TE2B0', 4.5, ... % ms
                'TRB0', 30, ... % ms
                'ADCSamplesB0', 2048 ...
            );

        end

        function plotMultiPanel(app, W)
            % Clear plots
            cla(app.UIAxes4); cla(app.UIAxes5); cla(app.UIAxes6); cla(app.UIAxes7); cla(app.UIAxes8); cla(app.UIAxes9); 

            % RF magnitude
            plot(app.UIAxes4, W.rf.t * 1e3, abs(W.rf.signal) * 1e3, 'k.');
            ylabel(app.UIAxes4, '|B1| (mT)');

            % RF phase
            plot(app.UIAxes5, W.rf.t * 1e3, angle(W.rf.signal), 'k.');
            ylabel(app.UIAxes5, 'Phase (rad)');
            ylim(app.UIAxes5, [-pi pi]);
            yticks(app.UIAxes5, [-pi 0 pi]);
            yticklabels(app.UIAxes5, {'-\pi', '0', '\pi'});

            % Gx
            plot(app.UIAxes6, W.gx.t * 1e3, W.gx.signal, 'r.-');
            ylabel(app.UIAxes6, 'Gx (G/cm)');

            % Gy
            plot(app.UIAxes7, W.gy.t * 1e3, W.gy.signal, 'g.-');
            ylabel(app.UIAxes7, 'Gy (G/cm)');

            % Gz
            plot(app.UIAxes9, W.gz.t * 1e3, W.gz.signal, 'b.-');
            ylabel(app.UIAxes9, 'Gz (G/cm)');

            % PNS
            plot(app.UIAxes8, W.pns.t * 1e3, W.pns.signal, 'm-');
            ylabel(app.UIAxes8, 'PNS (%)');

            xlabel(app.UIAxes8, 'Time (ms)');
            linkaxes([app.UIAxes4, app.UIAxes5, app.UIAxes6, app.UIAxes7, app.UIAxes8, app.UIAxes9], 'x');
        end

        function forceCompileInclusion(app)
            % Include external functions
            if false
                % mainPSD
                mainMJGgre2dgui;
                mainSpiralB00630;
                mainSpiralfs;

                % recon
                rds_recon_core;
                recon_nufft_guiV2;
                recon_nufft_greb0;
                rds_spiral;
                GERecon; 
            end
        end

        function out = mergeStructs(app, a, b)
            out = a;
            fn = fieldnames(b);
            for i = 1:numel(fn)
                out.(fn{i}) = b.(fn{i});
            end            
        end

        function cv = parseCvsKeyValueFileFlexible(app, cvsFile)

            fid = fopen(cvsFile,'rt');
            if fid < 0
                error(['Cannot open file: ' cvsFile]);
            end    
            cleanupObj = onCleanup(@() fclose(fid));       
            cv = struct();   
            while true        
                line = fgetl(fid);      
                if ~ischar(line)
                    break;
                end
            
                line = strtrim(line);
            
                if isempty(line)
                    continue;
                end
            
                tok = regexp(line, ...
                    '^(?<key>[A-Za-z0-9_\.]+)\s*=\s*"?(?<val>.*?)"?$', ...
                    'names');
            
                if ~isempty(tok)
            
                    key = matlab.lang.makeValidName(tok.key);
                    cv.(key) = tok.val;
                    continue;
            
                end
            
                parts = regexp(line,'\s+','split');
            
                if numel(parts) >= 2
            
                    key = matlab.lang.makeValidName(parts{1});
                    cv.(key) = parts{2};
            
                end
            
            end
            
        end

        function updateReconDisplay(app)
            reconS = app.ReconState;

             % K-space (log)
            imagesc(app.KSpaceAxes, reconS.klog.');
            axis(app.KSpaceAxes, 'image'); 
            title(app.KSpaceAxes, 'Log K-space');
        
            % Magnitude
            imagesc(app.MagnitudeAxes, reconS.mag.');
            axis(app.MagnitudeAxes, 'image');
            title(app.MagnitudeAxes, 'Magnitude');
        
            % Phase
            imagesc(app.PhaseAxes, reconS.phase.');
            axis(app.PhaseAxes, 'image');
            title(app.PhaseAxes, 'Phase');
        
            % Optional consistent colormap
            colormap(app.UIFigure, gray);
        end

        function paths = loadAppPaths(app, filepath)
            % A function to load app_path.txt file (where the user can
            % specify paths)
            paths = struct('run_dir', '', 'config_dir', '', 'recon_dir', '', 'pge_dir', '', 'rdsexe_dir', '', 'spiral_recon_dir', '', 'rds_data_dir', '');

            fid = fopen(filepath, 'r');
            if fid == -1
                warning('Cannot open file: %s', filepath);
                return;
            end
            if ~isfile(filepath)
                warning('app_path.txt file not found: %s', filepath);
                return;
            end

            cleanupObj = onCleanup(@() fclose(fid));
            
            fid = fopen(filepath, 'r');
            while ~feof(fid)
                line = strtrim(fgetl(fid));
                if isempty(line) || startsWith(line, '#')
                    continue;
                end

                tokens = split(line, '=');
                if numel(tokens) ~= 2
                    continue;
                end
                
                key = strtrim(tokens{1});
                val = strtrim(tokens{2});
                if isfield(paths, key)
                    paths.(key) = val;
                end
            end
            
        end

        function saveAppPaths(app, filepath, paths)
            % Helper function to save paths to txt file
            fid = fopen(filepath, 'w');

            if fid == -1
                app.StatusTextArea.Value = "Error: Could not open app_path.txt.";
                return;
            end

            cleanupObj = onCleanup(@() fclose(fid));

            fprintf(fid, 'run_dir=%s\n', paths.run_dir);
            fprintf(fid, 'config_dir=%s\n', paths.config_dir);
            fprintf(fid, 'recon_dir=%s\n', paths.recon_dir);
            fprintf(fid, 'pge_dir=%s\n', paths.pge_dir);
            fprintf(fid, 'rdsexe_dir=%s\n', paths.rdsexe_dir);
            fprintf(fid, 'spiral_recon_dir=%s\n', paths.spiral_recon_dir);
            fprintf(fid, 'rds_data_dir=%s\n', paths.rds_data_dir);
            
            % Update on GUI
            app.StatusTextArea.Value = [ app.StatusTextArea.Value 
                {
                    'Paths saved succesfully:'
                    ['Run directory: ' paths.run_dir]
                    ['Config directory: ' paths.config_dir]
                    ['Recon directory: ' paths.recon_dir]
                    ['pge directory: ' paths.pge_dir]
                    ['RDS exe directory: ' paths.rdsexe_dir]
                    ['Spiral recon directory: ' paths.spiral_recon_dir]
                    ['RDS data directory: ' paths.rds_data_dir]
                };
             ];

        end

        function updatePathsFile(app)
            % A function to update paths variable and save paths to txt file
            paths.run_dir = app.RunDirectoryEditField.Value;
            paths.config_dir = app.ConfigDirectoryEditField.Value;
            paths.recon_dir = app.ReconDirectoryEditField.Value;
            paths.pge_dir = app.PgeDir;
            paths.rdsexe_dir = app.RDSDirectoryEditField.Value;
            paths.spiral_recon_dir = app.SpiralReconPathEditField.Value;
            paths.rds_data_dir = app.RDSDataDirectoryEditField.Value;

            app.saveAppPaths(app.AppPathFile, paths);
        end

        function savePulseqParams(app)
            % A function to write out parameters to app_config.txt, to
            % be read by pulseq.e
            % Validate PGE directory
            if ~exist(app.PgeDir, 'dir')
                uialert(app.UIFigure, ...
                    ['PGE directory does not exist: ' app.PgeDir], ...
                    'Error');
                return;
            end

            % Params struct: to overwrite variables in pulseq.e
            pulseq_params = struct( ...
                'dda', app.DummyEditField.Value, ...
                'pislquant', app.ReceiveGainCalibrationEditField.Value, ...
                'entryNo', app.pgeXentryEditField.Value, ...
                'nint', app.NintEditField.Value);

            % Write to app_config.txt
            paramFile = fullfile(app.PgeDir, 'app_config.txt');
            fid = fopen(paramFile, 'w');

            if fid == -1
                uialert(app.UIFigure, ...
                    ['Failed to write:' paramFile], ...
                    'File Error');
                return;
            end

            cleanupObj = onCleanup(@() fclose(fid));
            
            % Add pge_dir
            fprintf(fid, 'pge_dir=%s\n', strtrim(app.PgeDir));

            % Loop through struct fields
            fields = fieldnames(pulseq_params);
            for i = 1:numel(fields)
                key = fields{i};
                value = pulseq_params.(key);
                
                fprintf(fid, '%s=%g\n', key, value);
            end

            app.logMessage(['pulseq_params written to: ' paramFile]);

        end

        function selectReconFile(app, fileField)
            % A function to select reconsturciton files for spiral
            % reconstruction
            startPath = app.SpiralReconPathEditField.Value;
            [file, path] = uigetfile('*.*', 'Select File', startPath);
            if isequal(file, 0)
                return
            end
            
            fileField.Value = fullfile(path, file);
        end

        function plotSpiralRecon(app, ax, img, cmap, title_name, addColorbar)
            % A function used in the RunSpiralRecon butter to visualize the
            % reconstructions
            cla(ax);
            imagesc(ax, img);
            axis(ax, 'image');
            ax.XTick = [];
            ax.YTick = [];

            colormap(ax, cmap);
            title(ax, title_name);

            if nargin >= 6 && addColorbar
                colorbar(ax);
                clim(ax, [-400 400]);
            end
        end

        function clearSpiralReconPlots(app)
            % A function to clean up the plots used for spiral
            % reconstruction
            cla(app.Reconwithoutb0);
            cla(app.Reconwithb0);
            cla(app.Difference);
            cla(app.B0Img1);
            cla(app.B0Img2);
            cla(app.B0Phase1);
            cla(app.B0Phase2);
            cla(app.zmap);
            cla(app.zmaphr);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            scr = get(groot,'ScreenSize');

            w = min(1100, scr(3)-40);
            h = min(900,  scr(4)-80);
            
            app.UIFigure.WindowState = 'normal';
            app.UIFigure.Position = [20 20 w h];
                        
            
            %#function mainMJGgre2dgui
            %#function mainSpiralB00630
            %#function mainSpiralfs
            %#function rds_recon_core
            %#function recon_nufft_guiV2
            %#function recon_nufft_greb0
            %#function rds_spiral
            %#function GERecon

            % Set read-only resource directory (all packaged directories)
            if isdeployed
                app.ResourceDir = ctfroot;
            else
                app.ResourceDir = fileparts(mfilename('fullpath'));
          
            end
            addpath(genpath(app.ResourceDir));    % comment out for
%             scanner

            % Include functions
            app.forceCompileInclusion();

            % Set default GUI params
            app.defaultParams = app.getDefaultParams();
            app.FreqFOVcmEditField.Value = app.defaultParams.fov_x;
            app.PhaseFOVcmEditField.Value = app.defaultParams.fov_y;
            app.SliceThicknessmmEditField.Value = app.defaultParams.slice_thickness;
            app.SliceGapmmEditField.Value = app.defaultParams.sliceGap;
            app.ADCDwellmsEditField.Value = app.defaultParams.dwell;
            app.TEmsEditField.Value = app.defaultParams.TE;
            app.TRmsEditField.Value = app.defaultParams.TR;
            app.RFSpoilingIncrementEditField.Value = app.defaultParams.rfSpoilingInc;
            app.NxEditField.Value = app.defaultParams.Nx;
            app.NyEditField.Value = app.defaultParams.Ny;
            app.FlipAngleEditField.Value = app.defaultParams.alpha;
            app.ReceiveGainCalibrationEditField.Value = app.defaultParams.pislquant;
            app.DummyEditField.Value = app.defaultParams.dda;
            app.MinTECheckBox.Value = app.defaultParams.minTE;
            app.MinTRCheckBox.Value = app.defaultParams.minTR;
            app.RFSpoilingCheckBox.Value = app.defaultParams.rfSpoil;
            app.OversamplingEditField.Value = app.defaultParams.oversampling;
            app.BandwidthkHzEditField.Value = app.defaultParams.BW;
            app.NintEditField.Value = app.defaultParams.Nint;
            app.NslicesEditField.Value = app.defaultParams.NSlices;
            app.fatsatCheckBox.Value = app.defaultParams.fatSat;
            app.ADCSamplesEditField.Value = app.defaultParams.ADCSamples;
            app.B0CheckBox.Value = app.defaultParams.doB0Map;
            app.NB0EditField.Value = app.defaultParams.NB0;
            app.NintB0EditField.Value = app.defaultParams.NintB0;
            app.FlipAngleB0EditField.Value = app.defaultParams.alphaB0;
            app.TE1B0msEditField.Value = app.defaultParams.TE1B0;
            app.TE2B0msEditField.Value = app.defaultParams.TE2B0;
            app.TRB0msEditField.Value = app.defaultParams.TRB0;
            app.ADCSamplesB0EditField.Value = app.defaultParams.ADCSamplesB0;

        end

        % Button pushed function: SetDirectoryButton
        function SetDirectoryButtonPushed(app, event)
            %%% RUN DIR %%%
            % Set run directory -- need to be manually entered
            userDir = strtrim(app.RunDirectoryEditField.Value);
            if isempty(userDir)
                uialert(app.UIFigure, ...
                    'Run directory cannot be empty.', ...
                    'Invalid Input');
                return;
            end
            
            app.RunDir = userDir;

            % Check run directory
            if ~exist(app.RunDir, 'dir')
                mkdir(app.RunDir);
            end
            
            testfile = fullfile(app.RunDir, 'test.tmp');
            fid = fopen(testfile, 'w');

            if fid < 0
                uialert(app.UIFigure, ...
                    'Run directory is not writable.', ...
                    'Permission Error');
                return;
            end
            
            fclose(fid);
            delete(testfile);

            % Initialize log file
            app.LogFile = fullfile(app.RunDir, 'app_log.txt');
            fid = fopen(app.LogFile, 'a');
            if fid < 0
                uialert(app.UIFigure,'Failed to initialize log file.', 'Error');
                return;
            end
            fprintf(fid, '\n--- New Session: %s ---\n', datestr(now));
            fclose(fid);

            app.logMessage('Log file initialized.');

            % Print out resource directory
            app.logMessage(['Resource directory: ' app.ResourceDir]);

            % Print the set run directory 
            app.logMessage(['Run directory: ' app.RunDir]);
            
            %%% APP PATH %%%
            % Set default run directory path
            appPath = app.RunDir;

            % Load paths from app_path.txt
            app.AppPathFile = fullfile(appPath, 'app_path.txt');
            paths = app.loadAppPaths(app.AppPathFile);

            % Set default run, config, and recon directories
            app.ConfigDirectoryEditField.Value = paths.config_dir;
            app.ReconDirectoryEditField.Value = paths.recon_dir;
            app.ConfigDir = paths.config_dir;
            app.ReconDir = paths.recon_dir;
            app.RDSDirectoryEditField.Value = paths.rdsexe_dir;
            app.SpiralReconPathEditField.Value = paths.spiral_recon_dir;
            app.RDSDataDirectoryEditField.Value = paths.rds_data_dir;

            if ~isempty(paths.pge_dir)
                app.PgeDir = paths.pge_dir;
            else
                app.PgeDir = app.RunDir;
            end

            if ~exist(app.PgeDir, 'dir')
                uialert(app.UIFigure, ...
                    ['PGE directory not found: ' app.PgeDir], ...
                    'Startup Error');
            end

            app.logMessage('Default directories loaded.');
            
            %%% CONFIG %%%
            % Initialize config directory
            newConfigDir = strtrim(app.ConfigDirectoryEditField.Value);
            if exist(newConfigDir, 'dir')
                app.ConfigDir = newConfigDir;
            else
                uialert(app.UIFigure, 'Invalid config directory.', 'Error');
                return;
            end

            app.SelectedConfigFiles = {};
            app.ConfigListBox.Items = {};
            app.ScannerConfigurationsTextArea.Value = {'No config loaded.'};
           
            %%% MAIN %%%
            % AK replaced the compiled version with hardcoded list since
            % compiled apps cannot run .m directly
            app.MainPSDDropDown.Items = {
                'mainMJGgre2dgui'
                'mainSpiralB00630'
                'mainSpiralfs'
            };

            %%% RECON %%%
            newReconDir = strtrim(app.ReconDirectoryEditField.Value);
            if exist(newReconDir, 'dir')
                app.ReconDir = newReconDir;
            else
                uialert(app.UIFigure, 'Invalid recon directory.', 'Error');
                return;
            end

            app.logMessage(['Run directory set: ' app.RunDir]);
            app.logMessage(['Config directory set: ' app.ConfigDir]);
            app.logMessage(['Recon directory set: ' app.ReconDir]);

            % Update changes
            app.updatePathsFile();
        end

        % Button pushed function: GeneratePSDButton
        function GeneratePSDButtonPushed(app, event)
            % Check merged config file
            if isempty(fieldnames(app.MergedCV))
                uialert(app.UIFigure, ...
                    'Please merge config files first.', ...
                    'Error');
                return;
            end
            
            % Check mainPSD file
            if isempty(app.MainPSDDropDown.Items)
                uialert(app.UIFigure, 'Please select a mainPSD file.', 'Error');
                return;
            end

            % Get selected mainPSD
            selectedFile = app.MainPSDDropDown.Value;
            [~, mainName] = fileparts(selectedFile);

            % Sequence parameters
            gui_params = struct( ...
                'fov_x', app.FreqFOVcmEditField.Value*0.01, ...  % cm to m
                'fov_y', app.PhaseFOVcmEditField.Value*0.01, ...  % cm to m
                'slice_thickness', app.SliceThicknessmmEditField.Value*0.001, ...  % mm to m
                'sliceGap', app.SliceGapmmEditField.Value*0.001, ...     % mm to cm
                'dwell', app.ADCDwellmsEditField.Value*0.001, ...  % ms to s
                'TE', app.TEmsEditField.Value*0.001, ...  % ms to s
                'TR', app.TRmsEditField.Value*0.001, ...  % ms to s
                'rfSpoilingInc', app.RFSpoilingIncrementEditField.Value, ...
                'Nx', app.NxEditField.Value, ...
                'Ny', app.NyEditField.Value, ...
                'alpha', app.FlipAngleEditField.Value, ...
                'pislquant', app.ReceiveGainCalibrationEditField.Value, ...
                'dda', app.DummyEditField.Value, ...
                'minTE', app.MinTECheckBox.Value, ...
                'minTR', app.MinTRCheckBox.Value, ...
                'rfSpoil', app.RFSpoilingCheckBox.Value, ...
                'oversampling', app.OversamplingEditField.Value, ...
                'BW', app.BandwidthkHzEditField.Value * 1e3, ... % kHz to Hz
                'Nint', app.NintEditField.Value, ...
                'NSlices', app.NslicesEditField.Value, ...
                'fatSat', app.fatsatCheckBox.Value, ...
                'ADCSamples', app.ADCSamplesEditField.Value, ...
                'doB0Map', app.B0CheckBox.Value, ...
                'NB0', app.NB0EditField.Value, ...
                'NintB0', app.NintB0EditField.Value, ...
                'alphaB0', app.FlipAngleB0EditField.Value, ...
                'TE1B0', app.TE1B0msEditField.Value*0.001, ...  % ms to s
                'TE2B0', app.TE2B0msEditField.Value*0.001, ...  % ms to s
                'TRB0', app.TRB0msEditField.Value*0.001, ...  % ms to s
                'ADCSamplesB0', app.ADCSamplesB0EditField.Value ...
            );

            % Check sequence name and entry value
            if isempty(app.SeqnameEditField.Value)
                uialert(app.UIFigure, 'Please enter a sequence name.', 'Error');
                return;
            end
            if isnan(app.pgeXentryEditField.Value)
                uialert(app.UIFigure, 'Please enter an entry file value.', 'Error');
                return;
            end

            % Generate PSD by calling mainPSD
            % With try/catch: error message sent to a popup window
            % mainPSD(baseDir, configFile, gui_params)
            try
                switch mainName
                    case 'mainMJGgre2dgui'
                        fh = str2func(mainName);

                        app.logMessage(['Calling function: ' func2str(fh)], 'DEBUG');
                        whichResult = which(mainName);
                        app.logMessage(['which() result: ' whichResult], 'DEBUG');
                        
                        [configLines, seq, S, rep, measured] = fh(app.PgeDir, app.SeqnameEditField.Value, app.MergedCV, gui_params);
                    case 'mainSpiralB00630'
                        fh = str2func(mainName);
                        [configLines, seq, S, rep, measured] = fh(app.PgeDir, app.SeqnameEditField.Value, app.MergedCV, gui_params);
                    case 'mainSpiralfs'
                        fh = str2func(mainName);
                        [configLines, seq, S, rep, measured] = fh(app.PgeDir, app.SeqnameEditField.Value, app.MergedCV, gui_params);
                    
                    otherwise
                        error('Unknown main.')
                end
                app.seq = seq;
                app.S = S;
%                 app.StatusTextArea.Value = {rep{:}};
                app.StatusTextArea.Value = [ ...
                                            rep, ...
                                            {''}, ...
                                            measured];
               app.ScannerConfigurationsTextArea.Value = configLines;
                app.logMessage('PSD generated successfully.');

                % Generate a .entry file
                % Read sequence name from the GUI
                seq_name = app.SeqnameEditField.Value;
                pgeFileName = fullfile(app.PgeDir, sprintf('%s.pge', seq_name));
                % Read the entry number
                entryNo = app.pgeXentryEditField.Value;
                entryName = sprintf('pge%d.entry', entryNo);
                entryFile = fullfile(app.PgeDir, entryName);
                fentry = fopen(entryFile, 'w');
                if fentry < 0
                    uialert(app.UIFigure, 'Failed to initialize entry file.', 'Error');
                    return;
                end
                fprintf(fentry, '1\n%s\n', pgeFileName);
                fclose(fentry);
                app.logMessage('Entry file generated successfully.');

                % Write out pulseq_params.txt
                app.savePulseqParams();

            catch ME

                % Short message in status
                app.logMessage(ME.message, 'ERROR');
            
                % Identifier
                app.logMessage(['Identifier: ' ME.identifier], 'DEBUG');
            
                % Which function user selected
                app.logMessage(['Selected mainName: ' mainName], 'DEBUG');
            
                % Types of variables passed in
                app.logMessage(['RunDir class: ' class(app.RunDir)], 'DEBUG');
                app.logMessage(['Seqname class: ' class(app.SeqnameEditField.Value)], 'DEBUG');
                app.logMessage(['gui_params class: ' class(gui_params)], 'DEBUG');
            
                % gui_params fields
                gpFields = fieldnames(gui_params);
                for i = 1:numel(gpFields)
                    fname = gpFields{i};
                    val = gui_params.(fname);
                    app.logMessage(sprintf('gui_params.%s = %s', ...
                        fname, mat2str(val)), 'DEBUG');
                end
            
                % Full stack trace
                fullReport = getReport(ME, 'extended', 'hyperlinks', 'off');
                app.logMessage(fullReport, 'DEBUG');
            
                % Popup
                uialert(app.UIFigure, ME.message, 'Execution error');
            end

        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % Clear dropdown
            app.MainPSDDropDown.Items = {};

            % Rescan directory
            app.MainPSDDropDown.Items = {
                'mainMJGgre2dgui'
                'mainSpiralB00630'
            };

            % Clear ktraj plot
            cla(app.UIAxes2);

            % Clear status box
            app.StatusTextArea.Value = {char("Refreshed at " + string(datetime('now')))};

            % Add to log
            if ~isempty(app.LogFile)
                app.logMessage('App refreshed.');
            end

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.LogFile = '';
            % app.BaseDirectoryEditField.Value = ''; % base dir field
            % removed
            app.MainPSDDropDown.Items = {};
            cla(app.UIAxes2);
            app.StatusTextArea.Value = {'App reset.'};
        end

        % Button pushed function: PlotkspaceTrajectoryButton
        function PlotkspaceTrajectoryButtonPushed(app, event)
            % Base filename entered in GUI
            baseFile = fullfile(app.PgeDir, app.SeqnameEditField.Value);
        
            % Construct filenames
            kspFile   = [baseFile '.ksp'];
            b0kspFile = [baseFile '.B0ksp'];
        
            % Check files exist
            if ~isfile(kspFile)
                % Check seq exists
                if isempty(app.seq)
                    uialert(app.UIFigure, ...
                        'Neither a .ksp file or seq object exists.', ...
                        'Error');
                    return;
                end
                % The ktraj plot is app.UIAxes2
                % ktraj_adc is the actual sampling points
                [ktraj_adc, ~, ~, ~, ~, ~] = app.seq.calculateKspacePP();
                % Plot the kspace trajectory from the .seq file
                cla(app.UIAxes2);
                plot(app.UIAxes2, ktraj_adc(1,:), ktraj_adc(2,:),'.');
                axis(app.UIAxes2, 'equal');
                grid(app.UIAxes2, 'on');
                title(app.UIAxes2, 'Pulseq Trajectory');
                return;
            end
        
            % Load normal k-space
            fid = fopen(kspFile,'rb');
            raw = fread(fid,[2 Inf],'double');
            kx = raw(1,:)';
            ky = raw(2,:)';
            fclose(fid);
        
            % Plot
            cla(app.UIAxes2);
            hold(app.UIAxes2,'on');
            plot(app.UIAxes2, kx,   ky,   '.', 'DisplayName','Trajectory');

            if isfile(b0kspFile)
                % Load B0 k-space
                fid = fopen(b0kspFile,'rb');
                rawB0 = fread(fid,[2 Inf],'double');
                kxB0 = rawB0(1,:)';
                kyB0 = rawB0(2,:)';
                fclose(fid);
                plot(app.UIAxes2, kxB0, kyB0, '.', 'DisplayName','B0 Trajectory');
            end
        
            axis(app.UIAxes2,'equal');
            grid(app.UIAxes2,'on');
            legend(app.UIAxes2,'Location','best'); 
            hold(app.UIAxes2,'off');
        end

        % Button pushed function: PlotSequenceDiagramButton
        function PlotSequenceDiagramButtonPushed(app, event)
            % Check S exists
            if isempty(app.S)
                uialert(app.UIFigure, 'Generate PSD first.', 'Error');
                return;
            end
            app.plotMultiPanel(app.S);

        end

        % Button pushed function: RunGREReconButton
        function RunGREReconButtonPushed(app, event)
            % Read reconstruction mode
            % Find the corresponding reconstruction code/function from
            % reconDir
            try

                reconDir = strtrim(app.ReconDirectoryEditField.Value);
                selectedPrep = app.RDSdataDropDown.Value;
                prepFile = fullfile(reconDir,selectedPrep);
                dataFile = strrep(prepFile, '.prep', '.data');
            
                if ~isfile(prepFile)
                    error('Missing prep file.');
                end
            
                if ~isfile(dataFile)
                    error('Missing data file.');
                end

                prescan = app.PrescanEditField.Value;
                nx = app.ReconNxEditField.Value;
                ny = app.ReconNyEditField.Value;
            
                app.logMessage('Running GRE reconstruction...');
                reconS = rds_recon_core(prepFile, dataFile, prescan, nx, ny, 0);
                app.ReconState = reconS;
                app.updateReconDisplay();
            
                app.logMessage('Recon completed.');
    
                exportapp(app.UIFigure, 'gre_recon.png');
            catch ME
            
                app.logMessage(ME.message,'ERROR');
                uialert(app.UIFigure, ME.message, 'GRE Recon Error');
            
            end
        end

        % Button pushed function: AddConfigFilesButton
        function AddConfigFilesButtonPushed(app, event)
            startDir = app.ConfigDir;
            if isempty(startDir) || ~exist(startDir, 'dir')
                startDir = app.RunDir;
            end


            [files, path] = uigetfile( ...
                {'*.cfg;*.cvs', 'Config Files (*.cfg, *.cvs)'}, ...
                'Select Config Files', ...
                startDir, ...
                'MultiSelect', 'on');

            if isequal(files, 0)
                return;
            end
            if ischar(files)
                files = {files};
            end

            for i = 1:numel(files)
                app.SelectedConfigFiles{end + 1} = ...
                    fullfile(path, files{i});
            end

            app.ConfigListBox.Items = app.SelectedConfigFiles;
        end

        % Button pushed function: RemoveConfigFileButton
        function RemoveConfigFileButtonPushed(app, event)
            idx = find(strcmp(app.ConfigListBox.Items, ...
            app.ConfigListBox.Value));
        
            if isempty(idx)
                return;
            end
        
            app.SelectedConfigFiles(idx) = [];
            app.ConfigListBox.Items = app.SelectedConfigFiles;
        
            if ~isempty(app.SelectedConfigFiles)
                app.ConfigListBox.Value = ...
                    app.SelectedConfigFiles{1};
            end

        end

        % Button pushed function: MoveUpConfigButton
        function MoveUpConfigButtonPushed(app, event)
            idx = find(strcmp(app.ConfigListBox.Items, ...
            app.ConfigListBox.Value));

            if isempty(idx) || idx == 1
                return;
            end
        
            tmp = app.SelectedConfigFiles{idx-1};
            app.SelectedConfigFiles{idx-1} = ...
                app.SelectedConfigFiles{idx};
            app.SelectedConfigFiles{idx} = tmp;
        
            app.ConfigListBox.Items = app.SelectedConfigFiles;
            app.ConfigListBox.Value = ...
                app.SelectedConfigFiles{idx-1};
        end

        % Button pushed function: MoveDownConfigButton
        function MoveDownConfigButtonPushed(app, event)
            idx = find(strcmp(app.ConfigListBox.Items, ...
            app.ConfigListBox.Value));

            if isempty(idx) || ...
               idx == numel(app.SelectedConfigFiles)
                return;
            end
        
            tmp = app.SelectedConfigFiles{idx+1};
            app.SelectedConfigFiles{idx+1} = ...
                app.SelectedConfigFiles{idx};
            app.SelectedConfigFiles{idx} = tmp;
        
            app.ConfigListBox.Items = app.SelectedConfigFiles;
            app.ConfigListBox.Value = ...
                app.SelectedConfigFiles{idx+1};
        end

        % Button pushed function: MergeConfigButton
        function MergeConfigButtonPushed(app, event)
            try 
                if isempty(app.SelectedConfigFiles)
                    uialert(app.UIFigure, ...
                        'No config files selected.', ...
                        'Error');
                    return;
                end
                hw = struct();
    
                for k = 1:numel(app.SelectedConfigFiles)
                    f = app.SelectedConfigFiles{k};
                    cfg = app.parseCvsKeyValueFileFlexible(f);
                    hw = app.mergeStructs(hw, cfg);
                end
    
                app.MergedCV = hw;
    
                fn = fieldnames(hw);
                txt = cell(numel(fn), 1);
                for i = 1:numel(fn)
                    val = hw.(fn{i});
                    if isnumeric(val)
                        txt{i} = sprintf('%s = %g', ...
                            fn{i}, val);
                    else
                        txt{i} = sprintf('%s = %s', ...
                            fn{i}, string(val));
                    end
                end
                app.ScannerConfigurationsTextArea.Value = txt;
                app.logMessage('Merged config.')
            catch ME
                uialert(app.UIFigure, ...
                    ME.message, ...
                    'Merge error');
            end
        end

        % Value changed function: ConfigDirectoryEditField
        function ConfigDirectoryEditFieldValueChanged(app, event)
            newDir = strtrim(app.ConfigDirectoryEditField.Value);
            if exist(newDir, 'dir')
                app.ConfigDir = newDir;

                % Update changes
                app.updatePathsFile();
                app.logMessage(['Config directory set: ' newDir]);
            else
                uialert(app.UIFigure, ...
                    'Folder does not exist.', ...
                    'Invalid folder.')
            end      
        end

        % Button pushed function: SetReconDirectoryButton
        function SetReconDirectoryButtonPushed(app, event)
            newDir = strtrim(app.ReconDirectoryEditField.Value);

            if exist(newDir,'dir')
                app.ReconDir = newDir;

                % Update changes
                app.updatePathsFile();
                app.logMessage(['Recon directory set: ' newDir]);
            else
                uialert(app.UIFigure, ...
                    'Folder does not exist.', ...
                    'Invalid Folder');
            end
        end

        % Button pushed function: ComparewithProductButton
        function ComparewithProductButtonPushed(app, event)
            app.logMessage('Running reconstruction...');
            % TODO: specify product files here
            reconDir = strtrim(app.ReconDirectoryEditField.Value);
            productPrepFile = fullfile(reconDir,'productGreOut.txt.prep');
            productDataFile = fullfile(reconDir,'productGreOut.txt.data');
            prescan = app.PrescanEditField.Value;
            nx = app.ReconNxEditField.Value;
            ny = app.ReconNyEditField.Value;

            productRecon = rds_recon_core(productPrepFile, productDataFile, prescan, nx, ny, 1);
            productMag = productRecon.mag.';
            reconMag = app.ReconState.mag.';
            
            % Normalize
            productMag = mat2gray(productMag);
            reconMag   = mat2gray(reconMag);
            
            % Product GRE magnitude
            imagesc(app.ProductAxes, productMag);
            axis(app.ProductAxes, 'image'); 
            title(app.ProductAxes, 'Product Magnitude');

            % Compare difference
            imagesc(app.DiffAxes, productMag - reconMag);
            axis(app.DiffAxes, 'image'); 
            title(app.DiffAxes, 'Difference');

            colormap(app.UIFigure, gray);
            
            % Quantitative comparison
            nrmseComp = sqrt(sum(abs(productMag(:) - reconMag(:)).^2)/sum(productMag(:).^2));
            ssimComp = ssim(productMag, reconMag);

            app.ReconComparisonTextArea.Value = {
                'Product and Pulseq comparison:'
                ['NRMSE: ' num2str(nrmseComp, '%.4f')]
                ['SSIM: ' num2str(ssimComp, '%.4f')]
            };
            exportapp(app.UIFigure, 'gre_recon.png');

        end

        % Button pushed function: RunRDSButton
        function RunRDSButtonPushed(app, event)
            rdsDataDir = strtrim(app.RDSDataDirectoryEditField.Value);
            rdsExeDir = strtrim(app.RDSDirectoryEditField.Value);
            timestamp = datestr(now,'yyyymmdd_HHMMSS');
            
            % output text file
            outFile = fullfile(rdsDataDir, ['out_' timestamp '.txt']);
        
            % construct command
            cmd = sprintf([ ...
                'cd "%s" && ' ...
                './RDSclient64 -f 4 -k -n all -o "%s"'], ...
                rdsExeDir, outFile);
        
            % run command
            [status, cmdout] = system(cmd);
        
            % display result
            if status == 0
                app.logMessage(['RDSclient64 output data: ' outfile]);
            else
                app.logMessage('RDSclient64 failed');
            end
        
            app.StatusTextArea.Value = splitlines(cmdout);

        end

        % Button pushed function: RefreshRDSButton
        function RefreshRDSButtonPushed(app, event)
            reconDir = strtrim(app.ReconDirectoryEditField.Value);
        
            if isempty(reconDir)
                reconDir = app.ReconDir;
            end
        
            if ~exist(reconDir,'dir')
                error('Recon folder does not exist.');
            end
            
            % Use drop down to select the file to be reconstructed
            prepFiles = dir(fullfile(reconDir, '*.txt.prep'));
            prepNames = {prepFiles.name};
            if isempty(prepNames)
                app.RDSdataDropDown.Items = {'<none found>'};
                app.RDSdataDropDown.Value = '<none found>';
            else
                app.RDSdataDropDown.Items = prepNames;

                % Choose latest file
                [~, idx] = max([prepFiles.datenum]);
                app.RDSdataDropDown.Value = prepNames{idx};
            end

            app.logMessage(['RDS data selected: ' app.RDSdataDropDown.Value]);
        end

        % Button pushed function: RunSpiralReconScanArchiveButton
        function RunSpiralReconScanArchiveButtonPushed(app, event)
            try
                clearSpiralReconPlots(app);
                spiral2DScanArchiveFile = app.ScanArchiveFileEditField.Value;
                kspfile = app.kspFileEditField.Value;
                b0kspfile = app.B0kspFileEditField.Value;

                if ~isfile(spiral2DScanArchiveFile)
                    error('Missing Spiral ScanArchive.');
                end

                SpiralRecon_params = struct( ...
                    'nleaf', app.NleafEditField.Value, ...
                    'HRNx', app.HighresNxEditField.Value, ...
                    'fovcm', app.FoVcmEditField.Value, ...
                    'pred_kmax', app.Predictedkmaxcm1EditField.Value, ...
                    'pislquant', app.CalibrationEditField.Value, ...
                    'dwell', app.DwellmsEditField.Value*1e-3, ...   % convert ms to s
                    'deltaTE', app.DualEchoDeltaTEmsEditField.Value*1e-3, ...    % convert ms to s
                    'nADC', app.SamplesperInterleafEditField.Value, ...
                    'useB0', app.B0ShotsEditField.Value, ...
                    'nxb0', app.B0NxEditField.Value, ...
                    'TE1b0', app.TE1msEditField.Value*1e-3, ...     % convert ms to s
                    'TE2b0', app.TE2msEditField.Value*1e-3, ...      % convert ms to s
                    'b0nADC', app.SamplesperB0InterleafEditField.Value ...
                );

                app.logMessage('Running Spiral reconstruction...');
                recon_outputs = recon_nufft_guiV2(spiral2DScanArchiveFile, SpiralRecon_params, kspfile, b0kspfile);
            
                % Plot reconstructions
                app.plotSpiralRecon(app.Reconwithoutb0, abs(recon_outputs.imsos), gray, 'Reconstruction without B0', false);

                if app.B0ShotsEditField.Value ~= 0
                    app.plotSpiralRecon(app.Reconwithb0, abs(recon_outputs.imsoswb0), gray, 'Reconstruction with B0', false);
                    app.plotSpiralRecon(app.Difference, abs(recon_outputs.imsoswb0) - abs(recon_outputs.imsos), gray, 'Difference', false);
                    app.plotSpiralRecon(app.B0Img1, abs(recon_outputs.b0img1), gray, 'B0 Img 1', false);
                    app.plotSpiralRecon(app.B0Img2, abs(recon_outputs.b0img2), gray, 'B0 Img 2', false);
                    app.plotSpiralRecon(app.B0Phase1, angle(recon_outputs.b0ims1), gray, 'Phase 1', false);
                    app.plotSpiralRecon(app.B0Phase2, angle(recon_outputs.b0ims2), gray, 'Phase 2', false);
                    app.plotSpiralRecon(app.zmap, recon_outputs.zmap, jet, 'zmap', true);
                    app.plotSpiralRecon(app.zmaphr, recon_outputs.zmap_hr, jet, 'High-res zmap', true);
                end

                exportapp(app.UIFigure, 'spiral_recon.png');
            
                app.logMessage('Spiral recon completed.');
            
            catch ME
                app.logMessage(ME.message,'ERROR');
                uialert(app.UIFigure, ME.message, 'Spiral Recon Error');
            end
        end

        % Button pushed function: BrowseScanArchiveButton
        function BrowseScanArchiveButtonPushed(app, event)
            selectReconFile(app, app.ScanArchiveFileEditField);
        end

        % Button pushed function: BrowsekspButton
        function BrowsekspButtonPushed(app, event)
            selectReconFile(app, app.kspFileEditField);
        end

        % Button pushed function: BrowseB0kspButton
        function BrowseB0kspButtonPushed(app, event)
            selectReconFile(app, app.B0kspFileEditField);
        end

        % Button pushed function: BrowseSpiralRDSButton
        function BrowseSpiralRDSButtonPushed(app, event)
            selectReconFile(app, app.SpiralRDSDataEditField);
        end

        % Button pushed function: RunSpiralReconRDSButton
        function RunSpiralReconRDSButtonPushed(app, event)
          
            try
                clearSpiralReconPlots(app);
                prepFile = app.SpiralRDSDataEditField.Value;
                dataFile = strrep(prepFile, '.prep', '.data');
            
                if ~isfile(prepFile)
                    error('Missing prep file.');
                end
            
                if ~isfile(dataFile)
                    error('Missing data file.');
                end

                kspfile = app.kspFileEditField.Value;
                b0kspfile = app.B0kspFileEditField.Value;

                SpiralRecon_params = struct( ...
                    'nleaf', app.NleafEditField.Value, ...
                    'HRNx', app.HighresNxEditField.Value, ...
                    'fovcm', app.FoVcmEditField.Value, ...
                    'pred_kmax', app.Predictedkmaxcm1EditField.Value, ...
                    'pislquant', app.CalibrationEditField.Value, ...
                    'dwell', app.DwellmsEditField.Value*1e-3, ...   % convert ms to s
                    'deltaTE', app.DualEchoDeltaTEmsEditField.Value*1e-3, ...    % convert ms to s
                    'nADC', app.SamplesperInterleafEditField.Value, ...
                    'useB0', app.B0ShotsEditField.Value, ...
                    'nxb0', app.B0NxEditField.Value, ...
                    'TE1b0', app.TE1msEditField.Value*1e-3, ...     % convert ms to s
                    'TE2b0', app.TE2msEditField.Value*1e-3, ...      % convert ms to s
                    'b0nADC', app.SamplesperB0InterleafEditField.Value ...
                );

                app.logMessage('Running Spiral RDS reconstruction...');
                recon_outputs = rds_spiral(prepFile, dataFile, SpiralRecon_params, kspfile, b0kspfile);
            
                % Plot reconstructions
                app.plotSpiralRecon(app.Reconwithoutb0, abs(recon_outputs.imsos), gray, 'Reconstruction without B0', false);

                if app.B0ShotsEditField.Value ~= 0
                    app.plotSpiralRecon(app.Reconwithb0, abs(recon_outputs.imsoswb0), gray, 'Reconstruction with B0', false);
                    app.plotSpiralRecon(app.Difference, abs(recon_outputs.imsoswb0) - abs(recon_outputs.imsos), gray, 'Difference', false);
                    app.plotSpiralRecon(app.B0Img1, abs(recon_outputs.b0img1), gray, 'B0 Img 1', false);
                    app.plotSpiralRecon(app.B0Img2, abs(recon_outputs.b0img2), gray, 'B0 Img 2', false);
                    app.plotSpiralRecon(app.B0Phase1, angle(recon_outputs.b0ims1), gray, 'Phase 1', false);
                    app.plotSpiralRecon(app.B0Phase2, angle(recon_outputs.b0ims2), gray, 'Phase 2', false);
                    app.plotSpiralRecon(app.zmap, recon_outputs.zmap, jet, 'zmap', true);
                    app.plotSpiralRecon(app.zmaphr, recon_outputs.zmap_hr, jet, 'High-res zmap', true);
                end

                exportapp(app.UIFigure, 'spiral_recon.png');
            
                app.logMessage('Spiral recon completed.');
            
            catch ME
                app.logMessage(ME.message,'ERROR');
                uialert(app.UIFigure, ME.message, 'Spiral Recon Error');
            end

        end

        % Button pushed function: BrowseDualEchoButton
        function BrowseDualEchoButtonPushed(app, event)
            selectReconFile(app, app.DualEchoGREEditField);
        end

        % Button pushed function: RunSpiralReconwithDualEchoButton
        function RunSpiralReconwithDualEchoButtonPushed(app, event)
            try
                clearSpiralReconPlots(app);
                spiral2DScanArchiveFile = app.ScanArchiveFileEditField.Value;
                kspfile = app.kspFileEditField.Value;
                grefile = app.DualEchoGREEditField.Value;

                if ~isfile(spiral2DScanArchiveFile)
                    error('Missing Spiral ScanArchive.');
                end

                SpiralRecon_params = struct( ...
                    'nleaf', app.NleafEditField.Value, ...
                    'HRNx', app.HighresNxEditField.Value, ...
                    'fovcm', app.FoVcmEditField.Value, ...
                    'pred_kmax', app.Predictedkmaxcm1EditField.Value, ...
                    'pislquant', app.CalibrationEditField.Value, ...
                    'dwell', app.DwellmsEditField.Value*1e-3, ...   % convert ms to s
                    'deltaTE', app.DualEchoDeltaTEmsEditField.Value*1e-3, ...    % convert ms to s
                    'nADC', app.SamplesperInterleafEditField.Value, ...
                    'useB0', app.B0ShotsEditField.Value, ...
                    'nxb0', app.B0NxEditField.Value, ...
                    'TE1b0', app.TE1msEditField.Value*1e-3, ...     % convert ms to s
                    'TE2b0', app.TE2msEditField.Value*1e-3, ...      % convert ms to s
                    'b0nADC', app.SamplesperB0InterleafEditField.Value ...
                );

                app.logMessage('Running Spiral reconstruction...');
                recon_outputs = recon_nufft_greb0(spiral2DScanArchiveFile, SpiralRecon_params, kspfile, grefile);
            
                % Plot reconstructions
                app.plotSpiralRecon(app.Reconwithoutb0, abs(recon_outputs.imsos), gray, 'Reconstruction without B0', false);
                app.plotSpiralRecon(app.Reconwithb0, abs(recon_outputs.imsoswb0), gray, 'Reconstruction with B0', false);
                app.plotSpiralRecon(app.Difference, abs(recon_outputs.imsoswb0) - abs(recon_outputs.imsos), gray, 'Difference', false);
                app.plotSpiralRecon(app.zmap, recon_outputs.zmap, jet, 'zmap', true);
                app.plotSpiralRecon(app.zmaphr, recon_outputs.zmap_hr, jet, 'High-res zmap', true);

                exportapp(app.UIFigure, 'spiral_recon_dual_echo.png');
            
                app.logMessage('Spiral recon completed.');
            
            catch ME
                app.logMessage(ME.message,'ERROR');
                uialert(app.UIFigure, ME.message, 'Spiral Recon Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SequenceTab = uitab(app.TabGroup);
            app.SequenceTab.Title = 'Sequence';
            app.UIAxes2 = uiaxes(app.SequenceTab);
            title(app.UIAxes2, 'kspace Trajectory')
            xlabel(app.UIAxes2, 'kx')
            ylabel(app.UIAxes2, 'ky')
            app.UIAxes4 = uiaxes(app.SequenceTab);
            app.UIAxes5 = uiaxes(app.SequenceTab);
            app.UIAxes6 = uiaxes(app.SequenceTab);
            app.UIAxes7 = uiaxes(app.SequenceTab);
            app.UIAxes8 = uiaxes(app.SequenceTab);
            app.UIAxes9 = uiaxes(app.SequenceTab);
            app.PlotkspaceTrajectoryButton = uibutton(app.SequenceTab, 'push');
            app.PlotkspaceTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @PlotkspaceTrajectoryButtonPushed, true);
            app.PlotkspaceTrajectoryButton.Text = 'Plot kspace Trajectory';
            app.PlotSequenceDiagramButton = uibutton(app.SequenceTab, 'push');
            app.PlotSequenceDiagramButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSequenceDiagramButtonPushed, true);
            app.PlotSequenceDiagramButton.Text = 'Plot Sequence Diagram';
            app.RefreshButton = uibutton(app.SequenceTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ResetButton = uibutton(app.SequenceTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SetDirectoryButton = uibutton(app.SequenceTab, 'push');
            app.SetDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetDirectoryButtonPushed, true);
            app.SetDirectoryButton.Text = 'Set Directory';
            app.GeneratePSDButton = uibutton(app.SequenceTab, 'push');
            app.GeneratePSDButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePSDButtonPushed, true);
            app.GeneratePSDButton.Text = 'Generate PSD';
            app.MinTECheckBox = uicheckbox(app.SequenceTab);
            app.MinTECheckBox.Text = 'Min TE';
            app.MinTRCheckBox = uicheckbox(app.SequenceTab);
            app.MinTRCheckBox.Text = 'Min TR';
            app.RFSpoilingCheckBox = uicheckbox(app.SequenceTab);
            app.RFSpoilingCheckBox.Text = 'RF Spoiling';
            app.AddConfigFilesButton = uibutton(app.SequenceTab, 'push');
            app.AddConfigFilesButton.ButtonPushedFcn = createCallbackFcn(app, @AddConfigFilesButtonPushed, true);
            app.AddConfigFilesButton.Text = 'Add Config Files';
            app.RemoveConfigFileButton = uibutton(app.SequenceTab, 'push');
            app.RemoveConfigFileButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveConfigFileButtonPushed, true);
            app.RemoveConfigFileButton.Text = 'Remove Config File';
            app.MoveUpConfigButton = uibutton(app.SequenceTab, 'push');
            app.MoveUpConfigButton.ButtonPushedFcn = createCallbackFcn(app, @MoveUpConfigButtonPushed, true);
            app.MoveUpConfigButton.Text = 'Move Up Config';
            app.MoveDownConfigButton = uibutton(app.SequenceTab, 'push');
            app.MoveDownConfigButton.ButtonPushedFcn = createCallbackFcn(app, @MoveDownConfigButtonPushed, true);
            app.MoveDownConfigButton.Text = 'Move Down Config';
            app.MergeConfigButton = uibutton(app.SequenceTab, 'push');
            app.MergeConfigButton.ButtonPushedFcn = createCallbackFcn(app, @MergeConfigButtonPushed, true);
            app.MergeConfigButton.Text = 'Merge Config';
            app.fatsatCheckBox = uicheckbox(app.SequenceTab);
            app.fatsatCheckBox.Text = 'fat sat';
            app.FreqFOVcmEditFieldLabel = uilabel(app.SequenceTab);
            app.FreqFOVcmEditFieldLabel.Text = 'Freq. FOV (cm)';
            app.FreqFOVcmEditField = uieditfield(app.SequenceTab, 'numeric');
            app.FreqFOVcmEditField.ValueDisplayFormat = '%.3f';
            app.PhaseFOVcmEditFieldLabel = uilabel(app.SequenceTab);
            app.PhaseFOVcmEditFieldLabel.Text = 'Phase FOV (cm)';
            app.PhaseFOVcmEditField = uieditfield(app.SequenceTab, 'numeric');
            app.PhaseFOVcmEditField.ValueDisplayFormat = '%.3f';
            app.SliceThicknessmmEditFieldLabel = uilabel(app.SequenceTab);
            app.SliceThicknessmmEditFieldLabel.Text = 'Slice Thickness (mm)';
            app.SliceThicknessmmEditField = uieditfield(app.SequenceTab, 'numeric');
            app.SliceThicknessmmEditField.ValueDisplayFormat = '%.3f';
            app.TRmsEditFieldLabel = uilabel(app.SequenceTab);
            app.TRmsEditFieldLabel.Text = 'TR (ms)';
            app.TRmsEditField = uieditfield(app.SequenceTab, 'numeric');
            app.TRmsEditField.ValueDisplayFormat = '%.3f';
            app.TEmsEditFieldLabel = uilabel(app.SequenceTab);
            app.TEmsEditFieldLabel.Text = 'TE (ms)';
            app.TEmsEditField = uieditfield(app.SequenceTab, 'numeric');
            app.TEmsEditField.ValueDisplayFormat = '%.3f';
            app.FlipAngleEditFieldLabel = uilabel(app.SequenceTab);
            app.FlipAngleEditFieldLabel.Text = 'Flip Angle';
            app.FlipAngleEditField = uieditfield(app.SequenceTab, 'numeric');
            app.ReceiveGainCalibrationEditFieldLabel = uilabel(app.SequenceTab);
            app.ReceiveGainCalibrationEditFieldLabel.Text = 'Receive Gain Calibration';
            app.ReceiveGainCalibrationEditField = uieditfield(app.SequenceTab, 'numeric');
            app.DummyEditFieldLabel = uilabel(app.SequenceTab);
            app.DummyEditFieldLabel.Text = 'Dummy';
            app.DummyEditField = uieditfield(app.SequenceTab, 'numeric');
            app.StatusTextAreaLabel = uilabel(app.SequenceTab);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.SequenceTab);
            app.mainPSDDropDownLabel = uilabel(app.SequenceTab);
            app.mainPSDDropDownLabel.Text = 'mainPSD';
            app.MainPSDDropDown = uidropdown(app.SequenceTab);
            app.NxEditFieldLabel = uilabel(app.SequenceTab);
            app.NxEditFieldLabel.Text = 'Nx';
            app.NxEditField = uieditfield(app.SequenceTab, 'numeric');
            app.NxEditField.ValueDisplayFormat = '%.0f';
            app.NyEditFieldLabel = uilabel(app.SequenceTab);
            app.NyEditFieldLabel.Text = 'Ny';
            app.NyEditField = uieditfield(app.SequenceTab, 'numeric');
            app.NyEditField.ValueDisplayFormat = '%.0f';
            app.ADCDwellmsEditFieldLabel = uilabel(app.SequenceTab);
            app.ADCDwellmsEditFieldLabel.Text = 'ADC Dwell (ms)';
            app.ADCDwellmsEditField = uieditfield(app.SequenceTab, 'numeric');
            app.ADCDwellmsEditField.ValueDisplayFormat = '%.3f';
            app.RFSpoilingIncrementEditFieldLabel = uilabel(app.SequenceTab);
            app.RFSpoilingIncrementEditFieldLabel.Text = 'RF Spoiling Increment';
            app.RFSpoilingIncrementEditField = uieditfield(app.SequenceTab, 'numeric');
            app.RFSpoilingIncrementEditField.ValueDisplayFormat = '%.0f';
            app.ScannerConfigurationsTextAreaLabel = uilabel(app.SequenceTab);
            app.ScannerConfigurationsTextAreaLabel.Text = 'Scanner Configurations';
            app.ScannerConfigurationsTextArea = uitextarea(app.SequenceTab);
            app.SeqnameEditFieldLabel = uilabel(app.SequenceTab);
            app.SeqnameEditFieldLabel.Text = 'Seq name';
            app.SeqnameEditField = uieditfield(app.SequenceTab, 'text');
            app.pgeXentryEditFieldLabel = uilabel(app.SequenceTab);
            app.pgeXentryEditFieldLabel.Text = 'pgeX.entry';
            app.pgeXentryEditField = uieditfield(app.SequenceTab, 'numeric');
            app.pgeXentryEditField.ValueDisplayFormat = '%.0f';
            app.OversamplingEditFieldLabel = uilabel(app.SequenceTab);
            app.OversamplingEditFieldLabel.Text = 'Oversampling';
            app.OversamplingEditField = uieditfield(app.SequenceTab, 'numeric');
            app.BandwidthkHzEditFieldLabel = uilabel(app.SequenceTab);
            app.BandwidthkHzEditFieldLabel.Text = 'Bandwidth (kHz)';
            app.BandwidthkHzEditField = uieditfield(app.SequenceTab, 'numeric');
            app.BandwidthkHzEditField.ValueDisplayFormat = '%.3f';
            app.NintEditFieldLabel = uilabel(app.SequenceTab);
            app.NintEditFieldLabel.Text = 'Nint';
            app.NintEditField = uieditfield(app.SequenceTab, 'numeric');
            app.RunDirectoryEditFieldLabel = uilabel(app.SequenceTab);
            app.RunDirectoryEditFieldLabel.Text = 'Run Directory';
            app.RunDirectoryEditField = uieditfield(app.SequenceTab, 'text');
            app.ConfigDirectoryEditFieldLabel = uilabel(app.SequenceTab);
            app.ConfigDirectoryEditFieldLabel.Text = 'Config Directory';
            app.ConfigDirectoryEditField = uieditfield(app.SequenceTab, 'text');
            app.ConfigDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @ConfigDirectoryEditFieldValueChanged, true);
            app.ConfigListBoxLabel = uilabel(app.SequenceTab);
            app.ConfigListBoxLabel.Text = 'Config';
            app.ConfigListBox = uilistbox(app.SequenceTab);
            app.NslicesEditFieldLabel = uilabel(app.SequenceTab);
            app.NslicesEditFieldLabel.Text = 'Nslices';
            app.NslicesEditField = uieditfield(app.SequenceTab, 'numeric');
            app.RDSDirectoryEditFieldLabel = uilabel(app.SequenceTab);
            app.RDSDirectoryEditFieldLabel.Text = 'RDS Directory';
            app.RDSDirectoryEditField = uieditfield(app.SequenceTab, 'text');
            app.RunRDSButton = uibutton(app.SequenceTab, 'push');
            app.RunRDSButton.ButtonPushedFcn = createCallbackFcn(app, @RunRDSButtonPushed, true);
            app.RunRDSButton.Text = 'Run RDS';
            app.ADCSamplesEditFieldLabel = uilabel(app.SequenceTab);
            app.ADCSamplesEditFieldLabel.Text = 'ADC Samples';
            app.ADCSamplesEditField = uieditfield(app.SequenceTab, 'numeric');
            app.B0CheckBox = uicheckbox(app.SequenceTab);
            app.B0CheckBox.Text = 'B0';
            app.NB0Label = uilabel(app.SequenceTab);
            app.NB0Label.Text = 'NB0';
            app.NB0EditField = uieditfield(app.SequenceTab, 'numeric');
            app.NintB0EditFieldLabel = uilabel(app.SequenceTab);
            app.NintB0EditFieldLabel.Text = 'NintB0';
            app.NintB0EditField = uieditfield(app.SequenceTab, 'numeric');
            app.FlipAngleB0EditFieldLabel = uilabel(app.SequenceTab);
            app.FlipAngleB0EditFieldLabel.Text = 'Flip Angle B0';
            app.FlipAngleB0EditField = uieditfield(app.SequenceTab, 'numeric');
            app.TE1B0msEditFieldLabel = uilabel(app.SequenceTab);
            app.TE1B0msEditFieldLabel.Text = 'TE1 B0 (ms)';
            app.TE1B0msEditField = uieditfield(app.SequenceTab, 'numeric');
            app.TE2B0msEditFieldLabel = uilabel(app.SequenceTab);
            app.TE2B0msEditFieldLabel.Text = 'TE2 B0 (ms)';
            app.TE2B0msEditField = uieditfield(app.SequenceTab, 'numeric');
            app.TRB0msEditFieldLabel = uilabel(app.SequenceTab);
            app.TRB0msEditFieldLabel.Text = 'TR B0 (ms)';
            app.TRB0msEditField = uieditfield(app.SequenceTab, 'numeric');
            app.ADCSamplesB0EditFieldLabel = uilabel(app.SequenceTab);
            app.ADCSamplesB0EditFieldLabel.Text = 'ADC Samples B0';
            app.ADCSamplesB0EditField = uieditfield(app.SequenceTab, 'numeric');
            app.RDSDataDirectoryEditFieldLabel = uilabel(app.SequenceTab);
            app.RDSDataDirectoryEditFieldLabel.Text = 'RDS Data Directory';
            app.RDSDataDirectoryEditField = uieditfield(app.SequenceTab, 'text');
            app.SliceGapmmEditFieldLabel = uilabel(app.SequenceTab);
            app.SliceGapmmEditFieldLabel.Text = 'Slice Gap (mm)';
            app.SliceGapmmEditField = uieditfield(app.SequenceTab, 'numeric');
            app.GREReconstructionTab = uitab(app.TabGroup);
            app.GREReconstructionTab.Title = 'GRE Reconstruction';
            app.KSpaceAxes = uiaxes(app.GREReconstructionTab);
            xlabel(app.KSpaceAxes, 'kx')
            ylabel(app.KSpaceAxes, 'ky')
            app.MagnitudeAxes = uiaxes(app.GREReconstructionTab);
            app.PhaseAxes = uiaxes(app.GREReconstructionTab);
            app.ProductAxes = uiaxes(app.GREReconstructionTab);
            app.DiffAxes = uiaxes(app.GREReconstructionTab);
            app.RunGREReconButton = uibutton(app.GREReconstructionTab, 'push');
            app.RunGREReconButton.ButtonPushedFcn = createCallbackFcn(app, @RunGREReconButtonPushed, true);
            app.RunGREReconButton.Text = 'Run GRE Recon';
            app.SetReconDirectoryButton = uibutton(app.GREReconstructionTab, 'push');
            app.SetReconDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetReconDirectoryButtonPushed, true);
            app.SetReconDirectoryButton.Text = 'Set Recon Directory';
            app.ComparewithProductButton = uibutton(app.GREReconstructionTab, 'push');
            app.ComparewithProductButton.ButtonPushedFcn = createCallbackFcn(app, @ComparewithProductButtonPushed, true);
            app.ComparewithProductButton.Text = 'Compare with Product';
            app.ReconDirectoryEditFieldLabel = uilabel(app.GREReconstructionTab);
            app.ReconDirectoryEditFieldLabel.Text = 'Recon Directory';
            app.ReconDirectoryEditField = uieditfield(app.GREReconstructionTab, 'text');
            app.PrescanEditFieldLabel = uilabel(app.GREReconstructionTab);
            app.PrescanEditFieldLabel.Text = 'Prescan';
            app.PrescanEditField = uieditfield(app.GREReconstructionTab, 'numeric');
            app.ReconNxEditFieldLabel = uilabel(app.GREReconstructionTab);
            app.ReconNxEditFieldLabel.Text = 'Recon Nx';
            app.ReconNxEditField = uieditfield(app.GREReconstructionTab, 'numeric');
            app.ReconNyEditFieldLabel = uilabel(app.GREReconstructionTab);
            app.ReconNyEditFieldLabel.Text = 'Recon Ny';
            app.ReconNyEditField = uieditfield(app.GREReconstructionTab, 'numeric');
            app.ReconComparisonTextAreaLabel = uilabel(app.GREReconstructionTab);
            app.ReconComparisonTextAreaLabel.Text = 'Recon Comparison';
            app.ReconComparisonTextArea = uitextarea(app.GREReconstructionTab);
            app.RDSdataDropDownLabel = uilabel(app.GREReconstructionTab);
            app.RDSdataDropDownLabel.Text = 'RDS data';
            app.RDSdataDropDown = uidropdown(app.GREReconstructionTab);
            app.RefreshRDSButton = uibutton(app.GREReconstructionTab, 'push');
            app.RefreshRDSButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshRDSButtonPushed, true);
            app.RefreshRDSButton.Text = 'Refresh RDS';
            app.SpiralReconstructionTab = uitab(app.TabGroup);
            app.SpiralReconstructionTab.Title = 'Spiral Reconstruction';
            app.Reconwithoutb0 = uiaxes(app.SpiralReconstructionTab);
            app.Reconwithb0 = uiaxes(app.SpiralReconstructionTab);
            app.Difference = uiaxes(app.SpiralReconstructionTab);
            app.B0Img1 = uiaxes(app.SpiralReconstructionTab);
            app.B0Img2 = uiaxes(app.SpiralReconstructionTab);
            app.B0Phase1 = uiaxes(app.SpiralReconstructionTab);
            app.B0Phase2 = uiaxes(app.SpiralReconstructionTab);
            app.zmap = uiaxes(app.SpiralReconstructionTab);
            app.zmaphr = uiaxes(app.SpiralReconstructionTab);
            app.RunSpiralReconScanArchiveButton = uibutton(app.SpiralReconstructionTab, 'push');
            app.RunSpiralReconScanArchiveButton.ButtonPushedFcn = createCallbackFcn(app, @RunSpiralReconScanArchiveButtonPushed, true);
            app.RunSpiralReconScanArchiveButton.Text = 'Run Spiral Recon ScanArchive';
            app.NleafEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.NleafEditFieldLabel.Text = 'Nleaf';
            app.NleafEditField = uieditfield(app.SpiralReconstructionTab, 'numeric');
            app.HighresNxEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.HighresNxEditFieldLabel.Text = 'High-res Nx';
            app.HighresNxEditField = uieditfield(app.SpiralReconstructionTab, 'numeric');
            app.Predictedkmaxcm1EditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.Predictedkmaxcm1EditFieldLabel.Text = 'Predicted kmax (cm^-1)';
            app.Predictedkmaxcm1EditField = uieditfield(app.SpiralReconstructionTab, 'numeric');
            app.CalibrationEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.CalibrationEditFieldLabel.Text = 'Calibration';
            app.CalibrationEditField = uieditfield(app.SpiralReconstructionTab, 'numeric');
            app.DwellmsEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.DwellmsEditFieldLabel.Text = 'Dwell (ms)';
            app.DwellmsEditField = uieditfield(app.SpiralReconstructionTab, 'numeric');
            app.HighresolutionLabel = uilabel(app.SpiralReconstructionTab);
            app.HighresolutionLabel.Text = 'High-resolution';
            app.B0ShotsEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.B0ShotsEditFieldLabel.Text = 'B0 Shots';
            app.B0ShotsEditField = uieditfield(app.SpiralReconstructionTab, 'numeric');
            app.B0NxEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.B0NxEditFieldLabel.Text = 'B0 Nx';
            app.B0NxEditField = uieditfield(app.SpiralReconstructionTab, 'numeric');
            app.TE1msEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.TE1msEditFieldLabel.Text = 'TE1 (ms)';
            app.TE1msEditField = uieditfield(app.SpiralReconstructionTab, 'numeric');
            app.TE2msEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.TE2msEditFieldLabel.Text = 'TE2 (ms)';
            app.TE2msEditField = uieditfield(app.SpiralReconstructionTab, 'numeric');
            app.B0Label = uilabel(app.SpiralReconstructionTab);
            app.B0Label.Text = 'B0';
            app.SpiralReconPathEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.SpiralReconPathEditFieldLabel.Text = 'Spiral Recon Path';
            app.SpiralReconPathEditField = uieditfield(app.SpiralReconstructionTab, 'text');
            app.ScanArchiveFileEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.ScanArchiveFileEditFieldLabel.Text = 'ScanArchive File';
            app.ScanArchiveFileEditField = uieditfield(app.SpiralReconstructionTab, 'text');
            app.kspFileEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.kspFileEditFieldLabel.Text = 'ksp File';
            app.kspFileEditField = uieditfield(app.SpiralReconstructionTab, 'text');
            app.B0kspFileEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.B0kspFileEditFieldLabel.Text = 'B0ksp File';
            app.B0kspFileEditField = uieditfield(app.SpiralReconstructionTab, 'text');
            app.BrowseScanArchiveButton = uibutton(app.SpiralReconstructionTab, 'push');
            app.BrowseScanArchiveButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseScanArchiveButtonPushed, true);
            app.BrowseScanArchiveButton.Text = 'Browse ScanArchive';
            app.BrowsekspButton = uibutton(app.SpiralReconstructionTab, 'push');
            app.BrowsekspButton.ButtonPushedFcn = createCallbackFcn(app, @BrowsekspButtonPushed, true);
            app.BrowsekspButton.Text = 'Browse ksp';
            app.BrowseB0kspButton = uibutton(app.SpiralReconstructionTab, 'push');
            app.BrowseB0kspButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseB0kspButtonPushed, true);
            app.BrowseB0kspButton.Text = 'Browse B0ksp';
            app.FoVcmEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.FoVcmEditFieldLabel.Text = 'FoV (cm)';
            app.FoVcmEditField = uieditfield(app.SpiralReconstructionTab, 'numeric');
            app.SpiralRDSDataEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.SpiralRDSDataEditFieldLabel.Text = 'Spiral RDS Data';
            app.SpiralRDSDataEditField = uieditfield(app.SpiralReconstructionTab, 'text');
            app.BrowseSpiralRDSButton = uibutton(app.SpiralReconstructionTab, 'push');
            app.BrowseSpiralRDSButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseSpiralRDSButtonPushed, true);
            app.BrowseSpiralRDSButton.Text = 'Browse Spiral RDS';
            app.RunSpiralReconRDSButton = uibutton(app.SpiralReconstructionTab, 'push');
            app.RunSpiralReconRDSButton.ButtonPushedFcn = createCallbackFcn(app, @RunSpiralReconRDSButtonPushed, true);
            app.RunSpiralReconRDSButton.Text = 'Run Spiral Recon RDS';
            app.SamplesperInterleafEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.SamplesperInterleafEditFieldLabel.Text = 'Samples per Interleaf';
            app.SamplesperInterleafEditField = uieditfield(app.SpiralReconstructionTab, 'numeric');
            app.SamplesperB0InterleafEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.SamplesperB0InterleafEditFieldLabel.Text = 'Samples per B0 Interleaf';
            app.SamplesperB0InterleafEditField = uieditfield(app.SpiralReconstructionTab, 'numeric');
            app.DualEchoGREEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.DualEchoGREEditFieldLabel.Text = 'Dual Echo GRE';
            app.DualEchoGREEditField = uieditfield(app.SpiralReconstructionTab, 'text');
            app.BrowseDualEchoButton = uibutton(app.SpiralReconstructionTab, 'push');
            app.BrowseDualEchoButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDualEchoButtonPushed, true);
            app.BrowseDualEchoButton.Text = 'Browse Dual Echo';
            app.RunSpiralReconwithDualEchoButton = uibutton(app.SpiralReconstructionTab, 'push');
            app.RunSpiralReconwithDualEchoButton.ButtonPushedFcn = createCallbackFcn(app, @RunSpiralReconwithDualEchoButtonPushed, true);
            app.RunSpiralReconwithDualEchoButton.Text = 'Run Spiral Recon with Dual Echo';
            app.DualEchoDeltaTEmsEditFieldLabel = uilabel(app.SpiralReconstructionTab);
            app.DualEchoDeltaTEmsEditFieldLabel.Text = 'Dual Echo Delta TE (ms)';
            app.DualEchoDeltaTEmsEditField = uieditfield(app.SpiralReconstructionTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = scanner_sim_ge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end